classdef PSG_chun < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LoadanadataMenu            matlab.ui.container.Menu
        LoadmatedfMenu             matlab.ui.container.Menu
        LoadstageMenu              matlab.ui.container.Menu
        LoadarousaldataMenu        matlab.ui.container.Menu
        LoadexpertstageMenu        matlab.ui.container.Menu
        AnalysisMenu               matlab.ui.container.Menu
        AutoScoringMenu            matlab.ui.container.Menu
        AutoScoringwithlowreliabilityMenu  matlab.ui.container.Menu
        expertdisscusionMenu       matlab.ui.container.Menu
        Panel                      matlab.ui.container.Panel
        AgreementLabel             matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        AgreementAnalysisButton    matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        PSGTab                     matlab.ui.container.Tab
        PSG_axes                   matlab.ui.control.UIAxes
        View_eventTab              matlab.ui.container.Tab
        event_axes                 matlab.ui.control.UIAxes
        channelDropDown            matlab.ui.control.DropDown
        ChannelLabel               matlab.ui.control.Label
        ZoomEditField              matlab.ui.control.NumericEditField
        ZoomEditFieldLabel         matlab.ui.control.Label
        save_Arousal               matlab.ui.control.Button
        PageEdit                   matlab.ui.control.NumericEditField
        PageLabel                  matlab.ui.control.Label
        fsEdit                     matlab.ui.control.NumericEditField
        SamplerateEditFieldLabel   matlab.ui.control.Label
        Brwose                     matlab.ui.control.Button
        savedddress                matlab.ui.control.EditField
        savedddressEditFieldLabel  matlab.ui.control.Label
        currentdata                matlab.ui.control.EditField
        currentdataEditFieldLabel  matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        save                       matlab.ui.control.Button
        stage_pic                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        mouse % Description
        
        total_arousal % Description
        data % Description
        fs % Description
        pagemax % Description
        page % Description
        fpath % Description
        fname % Description
        stage_data % Description
        zoom % Description
        currentchannel % Description
        channelnum % Description
        zeroline
        predict_data % Description
        tab % Description
        G_data % Description
        AHI_event
        hdr
        feat
        autostage
        nodestage
        stage_change_distance
        stage_change_freq
        slow_wave_related
        low_reliability
        subApp % Description
        arousal % Description
    end
    
    methods (Access = private)
        
        function draw_plot(app)
            switch app.tab
                case 1
                    cla(app.PSG_axes);
                    set(app.PSG_axes,'xLim',[1 app.fs*30])
                    set(app.PSG_axes,'xTick',(1:app.fs:30*app.fs));
                    set(app.PSG_axes,'xTickLabel',(0:30));
                    hold(app.PSG_axes,'on');
                    if ~isempty(app.data)
                        app.zeroline=zeros(app.fs*30,1);
                        if isempty(app.hdr)
                            for i=1:10
                                cname(i)="Channel "+num2str(i);
                            end
                        else
                            if length(app.hdr.label)>10
                                cname=string(app.hdr.label(1:10));  
                            else
                                cname=string(app.hdr.label(1:length(app.hdr.label))); 
                            end
                        end
                        set(app.PSG_axes,'yTickLabel',cname(min(app.channelnum,10):-1:1));
                        set(app.PSG_axes,'yLim',[-62.5 min(app.channelnum,10)*125-62.5 ])
                        set(app.PSG_axes,'yTick',(0:125:min(app.channelnum,10)*125-125));
                        
                        
                        stagename=["Mov","Rem","W","N1","N2","N3"];
                        text(app.PSG_axes,app.fs*15,0.5*min(app.channelnum,10)*125,stagename(app.stage_data(app.page)+3), 'HorizontalAlignment',...
                            'center',"FontSize",160,"Color",[0.75 0.75 0.75]);
                        colorstring(1:10)='k';
                        colorstring(2:2:10)='b';
                        if app.channelnum>10
                            app.channelnum=10;
                        end
                        for i=1:min(app.channelnum,10)
                            if (app.channelnum==4 && i<=2) || (app.channelnum==6 && i<=3) || (app.channelnum==10 && i<=6) || (app.channelnum==3)|| (app.channelnum==9 && i<=6)
                                plot(app.PSG_axes,app.zeroline+(app.channelnum-i)*125+37.5,'k--');
                                plot(app.PSG_axes,app.zeroline+(app.channelnum-i)*125-37.5,'k--');
                            end
                            plot(app.PSG_axes,app.data(i,(app.page-1)*30*app.fs+1:(app.page)*30*app.fs)+(min(10,app.channelnum)-i)*125-mean(app.data(i,(app.page-1)*30*app.fs+1:(app.page)*30*app.fs)),'Color',colorstring(i));
                            
                        end
                        
                    end
                    second=find(app.total_arousal(app.page,:)==1);
                    for i=1:length(second)
                        j=second(i);
                        fill(app.PSG_axes,[(j-1)*app.fs,(j-1)*app.fs,j*app.fs,j*app.fs],[-62.5,min(app.channelnum,10)*125-62.5,min(app.channelnum,10)*125-62.5,-62.5]...
                            ,'r','FaceAlpha',.25,'EdgeColor','none');
                        
                    end
                case 2
                    if ~isempty(app.data)
                        event_fs=app.hdr.frequency(11:21);
                        cla(app.event_axes);
                        set(app.event_axes,'xLim',[1 app.fs*30*4])
                        set(app.event_axes,'xTick',(1:app.fs*5:30*app.fs*4));
                        set(app.event_axes,'xTickLabel',(0:5:120));
                        hold(app.event_axes,'on');
                        app.zeroline=zeros(app.fs*30,1);
                        
                        cname=string(app.hdr.label(11:21));
                        
                        set(app.event_axes,'yTickLabel',cname(11:-1:1));
                        set(app.event_axes,'yLim',[-62.5 11*125-62.5 ])
                        set(app.event_axes,'yTick',(0:125:11*125-125));
                        
                        stagename=["Mov","Rem","W","N1","N2","N3"];
                        text(app.event_axes,app.fs*60,0.5*11*125,stagename(app.stage_data(app.page)+3), 'HorizontalAlignment',...
                            'center',"FontSize",160,"Color",[0.75 0.75 0.75]);
                        colorstring(1:11)='k';
                        colorstring(2:2:10)='b';
                        for i=11:21
                            head=(app.page-1)*30*event_fs(i-10)+1;
                            tail=(app.page+3)*30*event_fs(i-10);
                            x=(app.fs/event_fs(i-10):app.fs/event_fs(i-10):app.fs*30*4);
                            plot(app.event_axes,x,app.data(i,head:tail)+(app.channelnum-i)*125-mean(app.data(i,head:tail)),'Color',colorstring(i-10));
                        end
                    end
            end
        end
        
        function draw_stage(app)
            if ~isempty(app.data)
                cla(app.stage_pic);
                set(app.stage_pic,'xLim',[1 app.pagemax])
                set(app.stage_pic,'xTick',(1:100:app.pagemax));
                set(app.stage_pic,'yLim',[-4 2])
                set(app.stage_pic,'yTick',(-4:1:2));
                set(app.stage_pic,'yTickLabel',["","N3","N2","N1","W","R",""]);
                hold(app.stage_pic,"on");
                stairs(app.stage_pic,app.stage_data*-1);
                plot(app.stage_pic,[app.page app.page],[-4 2],'r');
                if ~isempty(app.predict_data)
                    stairs(app.stage_pic,app.predict_data*-1,'r');
                end
%                 if ~isempty(app.autostage)
%                     stairs(app.stage_pic,app.autostage*-1,'g');
%                 end
                if ~isempty(app.low_reliability)
                    for i=1:length(app.low_reliability)
                        block = patch(app.stage_pic,[app.low_reliability(i) app.low_reliability(i)+1 app.low_reliability(i)+1 app.low_reliability(i)], [4 4 -4 -4], 'b');
                        block.FaceAlpha = 0.3;block.EdgeAlpha = 0.3;
                    end
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.fs=200;
            app.fsEdit.Value=app.fs;
            app.data=[];
            disableDefaultInteractivity(app.PSG_axes);
            disableDefaultInteractivity(app.stage_pic);
            app.predict_data=[];
            app.tab=1;
            app.AHI_event=0;
            app.hdr=[];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            app.mouse=event.Source.CurrentPoint;
            plotposition=app.TabGroup.Position;
            stageposition=app.stage_pic.Position;
            if ~isempty(app.data)
                if app.channelnum<10
                    shift=80;
                else
                    shift=90;
                end
                
                if app.mouse(1)>shift && app.mouse(2)>plotposition(2) && app.mouse(2) < plotposition(2)+ plotposition(4)
                    second=floor((app.mouse(1)-shift)/(plotposition(3)-shift+20)*30);
                    app.total_arousal(app.page,second+1)= ~app.total_arousal(app.page,second+1);
                    draw_plot(app);
                elseif app.mouse(1)>40 && app.mouse(2)>stageposition(2) && app.mouse(2) < stageposition(2)+ stageposition(4)
                    app.page=round((app.mouse(1)-40)/(plotposition(3)-20)*app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                end
            end


        end

        % Value changed function: fsEdit
        function fsEditValueChanged(app, event)
            value = app.fsEdit.Value;
            app.fs=value;
            if ~isempty(app.data)
                app.pagemax=floor(length(app.data(1,:))/app.fs/30);
                app.total_arousal=zeros(app.pagemax,30);
                app.page=1;
                app.stage_data=zeros(app.pagemax,1);
                set(app.Slider,'Limits',[1 app.pagemax]);
                set(app.Slider,'Value',1);
                set(app.PageEdit,'Value',1);
            end
            draw_plot(app);
            draw_stage(app);
        end

        % Button pushed function: save
        function saveButtonPushed(app, event)
            csvwrite([app.fpath '\' app.fname(1:end-4) '.dat'],app.stage_data);
            f = msgbox('Save Completed');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            value=floor(value);
            if ~isempty(app.data)
                app.page=value;
                set(app.PageEdit,'Value',value);
                draw_plot(app);
                draw_stage(app);
            end
        end

        % Value changed function: PageEdit
        function PageEditValueChanged(app, event)
            value = app.PageEdit.Value;
            if ~isempty(app.data)
                app.page=min(value,app.pagemax);
                set(app.Slider,'Value',value);
                draw_plot(app);
                draw_stage(app);
            end
        end

        % Value changed function: savedddress
        function savedddressValueChanged(app, event)
            value = app.savedddress.Value;
            app.fpath=value;
        end

        % Button pushed function: Brwose
        function BrwoseButtonPushed(app, event)
            value=uigetdir('title','select the folder to save result');
            app.fpath=value;
            set(app.savedddress,'Value',value);
        end

        % Button pushed function: save_Arousal
        function save_ArousalButtonPushed(app, event)
            delete([app.fpath '\' app.fname(1:end-4) '_sleep index.xls']);
            result1=[];
            for i=1:app.pagemax
                tmp=find(app.total_arousal(i,:)==1);
                result1=[result1 tmp+30*(i-1)];
            end
            arousal_time=length(result1);
            if arousal_time==0
                f = msgbox('No arousal data');
            else
                for i=1:length(result1)-1
                    if result1(i)==result1(i+1)-1
                        arousal_time=arousal_time-1;
                    end
                end
                csvwrite([app.fpath '\' app.fname(1:end-4) '_arousal info.dat'],[arousal_time result1]);
                label1=["Total record time：","Total sleep time：","Stage 1：","Stage 2：","Stage 3：","Arousal Index："];
                label2=["Total sleep period：","Awake time：","REM：","Sleep Latency：","Efficiency："];
                unit1=["min","min","%","%","%","/h"];
                unit2=["min","min","%","min","%"];
                W=find(app.stage_data==0);
                NW=find(app.stage_data~=0);
                N1=find(app.stage_data==1);
                N2=find(app.stage_data==2);
                N3=find(app.stage_data==3);
                R=find(app.stage_data==-1);
                SOT=(NW(1)-1)/2;
                TRT=app.pagemax/2;%total record time
                TST=app.pagemax/2-length(W)/2;%total sleep period
                TSP=NW(end)/2-NW(1)/2;%total sleep time
                SE=100-length(W)/app.pagemax*100;
                ans1=[TRT,TST,length(N1)/TST/2*100,length(N2)/TST/2*100,length(N3)/TST/2*100,arousal_time/TST*60];
                ans2=[TSP,length(W)/2,length(R)/TST/2*100,SOT,SE];
                ans1=round(ans1,1);
                ans2=round(ans2,1);
                writematrix(label1',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'A1:A6');
                writematrix(ans1',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'B1:B6');
                writematrix(unit1',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'C1:C6');
                writematrix(label2',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'D1:D5');
                writematrix(ans2',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'E1:E5');
                writematrix(unit2',[app.fpath '\' app.fname(1:end-4) '_sleep index.xls'],"Range",'F1:F5');
                f = msgbox('Save Completed');
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case {'a','leftarrow'}
                    app.page=max(app.page-1,1);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case {'d','rightarrow'}
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case 'uparrow'
                    app.page=max(app.page-10,1);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case 'downarrow'
                    app.page=min(app.page+10,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case {'backquote','w'}
                    app.stage_data(app.page)=0;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case '1'
                    app.stage_data(app.page)=1;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case '2'
                    app.stage_data(app.page)=2;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case '3'
                    app.stage_data(app.page)=3;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case '4'
                    app.stage_data(app.page)=-1;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                case '5'
                    app.stage_data(app.page)=-2;
                    app.page=min(app.page+1,app.pagemax);
                    set(app.PageEdit,'Value',app.page);
                    set(app.Slider,'Value',app.page);
                    draw_stage(app);
                    draw_plot(app);
                    
            end
        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            value = app.ZoomEditField.Value;
            if app.currentchannel>app.channelnum
                for i=1:app.channelnum
                    app.data(i,:)=app.data(i,:)/app.zoom(i)*value;
                end
                app.zoom(:)=value;
            else
                app.data(app.currentchannel,:)=app.data(app.currentchannel,:)/app.zoom(app.currentchannel)*value;
                app.zoom(app.currentchannel)=value;
            end
            draw_plot(app);
        end

        % Value changed function: channelDropDown
        function channelDropDownValueChanged(app, event)
            value = app.channelDropDown.Value;
            if isequal("all",value)
                app.currentchannel=app.channelnum+1;
                
            else
                value=str2num(value);
                app.currentchannel=value;
                set(app.ZoomEditField,'Value',app.zoom(value));
            end
            
            
        end

        % Menu selected function: LoadarousaldataMenu
        function LoadarousaldataMenuSelected(app, event)
            [Name, Path] = uigetfile('*.DAT;*.dat','Select the arousal-Dat-file');  %total: *.*
            if ~isequal(app.fname,0)
                data = load([Path Name]); % load arousal stage data
                if length(data) > 1
                    for i=2:length(data)
                        app.total_arousal(floor(data(i)/30)+1,mod(data(i)-1,30)+1)=1;
                    end
                    
                    draw_plot(app);
                end
            end
        end

        % Menu selected function: LoadexpertstageMenu
        function LoadexpertstageMenuSelected(app, event)
            [Name, Path] = uigetfile('*.DAT;*.dat','Select the Predictstage-Dat-file');  %total: *.*
            if ~isequal(app.fname,0)
                app.predict_data = load([Path Name]); % load movement data
                draw_stage(app);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab.Title
                case'PSG'
                    app.tab=1;
                case 'View_event'
                    app.tab=2;
            end
            draw_plot(app);
        end

        % Menu selected function: LoadmatedfMenu
        function LoadmatedfMenuSelected(app, event)
            [app.fname, app.fpath] = uigetfile('*.MAT;*.mat;*.edf;*.EDF','Select the Mat-file');  %total: *.*
            if ~isequal(app.fname,0)
                if app.tab==1
                    app.predict_data=[];
                    if isequal(app.fname(end-3:end),".edf") || isequal(app.fname(end-3:end),".EDF")
                        [app.hdr, app.data] = edfread([app.fpath app.fname]);
                        app.fs=app.hdr.frequency(1);
                        app.fsEdit.Value=app.fs;
                    else
                        app.data = load([app.fpath app.fname]); % load mat data
                        F=fields(app.data);
                        app.data=getfield(app.data,F{1});
                    end
                    
                    if length(app.data(1,:))<length(app.data(:,1))
                        app.data=app.data';
                    end
                    app.pagemax=floor(length(app.data(1,:))/app.fs/30);
                    app.stage_data=zeros(app.pagemax,1);
                    app.total_arousal=zeros(app.pagemax,30);
                    app.page=1;
                    app.channelnum=length(app.data(:,1));
                    set(app.channelDropDown,'Items',[string(1:app.channelnum),"all"]);
                    set(app.channelDropDown,'Value',"all");
                    app.currentchannel=app.channelnum+1;
                    app.zoom=ones(app.channelnum,1);
                    set(app.ZoomEditField,'Value',1);
                    set(app.currentdata,'Value',[app.fpath app.fname]);
                    set(app.savedddress,'Value',app.fpath(1:end-1));
                    set(app.Slider,'Limits',[1 app.pagemax]);
                    set(app.Slider,'Value',1);
                    set(app.PageEdit,'Value',1);
                    draw_plot(app);
                    draw_stage(app);
                elseif app.tab==2
                    app.G_data = load([app.fpath app.fname]);
                    draw_plot(app);
                end
                
            end
        end

        % Menu selected function: LoadstageMenu
        function LoadstageMenuSelected(app, event)
            [Name, Path] = uigetfile('*.DAT;*.dat','Select the Dat-file');  %total: *.*
            if ~isequal(Name,0)
                tmp= load([Path Name]);
                if app.pagemax == length(tmp)
                    app.stage_data=tmp;
                    draw_stage(app);
                elseif app.pagemax>=length(tmp)
                    check_input = questdlg ({'資料長度不同，是否確認要覆蓋'},'長度不相同','確定','重新輸入','確定');
                    app.stage_data=zeros(app.pagemax,1);
                    app.stage(1:length(tmp))=tmp;
                end
            end
        end

        % Menu selected function: AutoScoringMenu
        function AutoScoringMenuSelected(app, event)
            app.feat = run_feat_chun(app.data',app.fs);
            [app.autostage app.nodestage] = run_purposed_chun(app.data',app.feat);
            %app.info = strvcat('size1: ',num2str(finish(1)),'size2: ',num2str(finish(2)),app.info);
            %set(app.text_info,'String',app.info);
            
            %%load auto stage
            if app.pagemax == length(app.autostage)
                    app.stage_data=app.autostage;
                    draw_stage(app);
            elseif app.pagemax>=length(app.autostage)
                check_input = questdlg ({'資料長度不同，是否確認要覆蓋'},'長度不相同','確定','重新輸入','確定');
                app.stage_data=zeros(app.pagemax,1);
                app.stage_data(1:length(tmp))=tmp;
            end
        end

        % Menu selected function: AutoScoringwithlowreliabilityMenu
        function AutoScoringwithlowreliabilityMenuSelected(app, event)
         
            app.feat = run_feat_chun(app.data',app.fs);
            [app.autostage app.nodestage] = run_purposed_chun(app.data',app.feat);
            count_from_head = ones(1,app.pagemax);
            count_from_bottom = ones(1,app.pagemax);
            count_changetime_from_head = zeros(1,app.pagemax);
            front_stage = app.autostage(1);
            next_stage = app.autostage(app.pagemax);
            startnum = 0;
            endnum = 0;
            SWR_feat_num = [1 28 20 44 4 31 18 42];
            SWR_bound = [0.45 1;    %1  (N3>N2)
                0.45 1;     %28
                0.3 0.7;    %20
                0.4 0.8;    %44
                0 0.4;      %4  (N2>N3)
                0 0.3;      %31
                0 0.5;      %18
                0 0.4;];    %42
            SWR_vote_pre = zeros(size(app.feat,1),length(SWR_feat_num));
            SWR_vote = [];
    
            for i = 2 : size(app.autostage,1)
                if front_stage ~= app.autostage(i)
                    front_stage = app.autostage(i);
                    count_changetime_from_head(i) = count_changetime_from_head(i-1) + 1;
                else
                    count_from_head(i) = count_from_head(i-1) + 1;
                end
        
                if next_stage ~= app.autostage(app.pagemax - i + 1)
                    next_stage = app.autostage(app.pagemax - i + 1);
                else
                    count_from_bottom(app.pagemax - i + 1) = count_from_bottom(app.pagemax - i + 2) + 1;
                end     
            end
    
            for i = 1:length(SWR_feat_num)
                SWR_vote_pre(:,i) = app.feat(:,SWR_feat_num(i)) > SWR_bound(i,1) & app.feat(:,SWR_feat_num(i)) < SWR_bound(i,2);
            end
            SWR_vote = SWR_vote_pre;
            for j = 1:length(SWR_feat_num)
                for k = 3:size(app.feat,1)-2
                    if SWR_vote_pre(k,j) == SWR_vote_pre(k-1,j) && SWR_vote_pre(k,j) == SWR_vote_pre(k-2,j) && SWR_vote_pre(k,j) == SWR_vote_pre(k+1,j) && SWR_vote_pre(k,j) == SWR_vote_pre(k+2,j)
                        SWR_vote(k,j) = 1; %0
                    else
                        SWR_vote(k,j) = 0; %1
                    end
                end     
            end
    
            for i = 1:length(SWR_vote)
                sum_SWR_vote(i) = sum(SWR_vote(i,:));
            end
            sum_SWR_vote=sum_SWR_vote';
    
            app.slow_wave_related=zeros(1,app.pagemax);
            app.stage_change_freq=zeros(1,app.pagemax);
            app.stage_change_distance=zeros(1,app.pagemax);
            app.arousal=zeros(1,app.pagemax);
            for i = 1 : size(app.autostage,1)
                %SCD
                app.stage_change_distance(i) = min(count_from_head(i),count_from_bottom(i));
                %SCF
                startnum = i - 2;
                endnum = i + 2
                if startnum <= 0
                    startnum = 1;
                end
                if endnum > app.pagemax
                    endnum = app.pagemax;
                end
                app.stage_change_freq(i) = count_changetime_from_head(endnum) - count_changetime_from_head(startnum);
        
                %SWR
                if sum_SWR_vote(i) >= 6 %2
                    app.slow_wave_related(i) = 1;
                end
                %Arousal
                if app.feat(i,50)>0 
                    if i<app.pagemax-3
                        tmp=[i-1:i+3];
                    else 
                        tmp=[i-1;i];
                    end
                    app.arousal(tmp)=1;
                end
            end
            %SWR(slow wave related)
            %print low reliability
            app.low_reliability = [];
            count_low_reliability = 1;
            for i = 1 : length(app.stage_change_distance)
                %if (app.stage_change_distance(i) < 2 && app.slow_wave_related(i) == 1) || (abs(app.stage_change_freq(i)) >= 2 && app.slow_wave_related(i) == 1)
                vote=0;
                if app.stage_change_distance(i) < 2
                    vote=vote+1;
                end
                if app.slow_wave_related(i) == 1
                    vote=vote+1;
                end
                if abs(app.stage_change_freq(i)) > 2
                    vote=vote+1;
                end
                app.arousal;
               if   vote>=2 || app.arousal(i)==1
                    app.low_reliability(count_low_reliability) = i;
                    %pred_stage_reliab(i)=human_sco(i);
                    count_low_reliability  = count_low_reliability + 1;
               end
            end
            
            if app.pagemax == length(app.autostage)
                app.stage_data=app.autostage;
                draw_stage(app);
            elseif app.pagemax>=length(app.autostage)
                check_input = questdlg ({'資料長度不同，是否確認要覆蓋'},'長度不相同','確定','重新輸入','確定');
                app.stage_data=zeros(app.pagemax,1);
                app.stage_data(1:length(tmp))=tmp;
            end
        end

        % Menu selected function: expertdisscusionMenu
        function expertdisscusionMenuSelected(app, event)
            
            if ~isempty(app.autostage)
            % Open the options dialog and pass inputs
               
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            delete(app.subApp);
        end

        % Button pushed function: AgreementAnalysisButton
        function AgreementAnalysisButtonPushed(app, event)
            app.AgreementAnalysisButton.Enable = 'off';
            app.subApp = app1(app,app.predict_data,app.stage_data,app.pagemax);
            if ~isempty(app.predict_data)&&~isempty(app.autostage)
                hyp1=app.autostage;
                hyp2=app.predict_data;
                app.Label.Text=round(length(find(hyp1==hyp2))/length(hyp1),3)*100+"%";
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.LoadanadataMenu = uimenu(app.UIFigure);
            app.LoadanadataMenu.Text = 'Load ana data';
            app.LoadmatedfMenu = uimenu(app.LoadanadataMenu);
            app.LoadmatedfMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadmatedfMenuSelected, true);
            app.LoadmatedfMenu.Text = 'Load mat/edf';
            app.LoadstageMenu = uimenu(app.LoadanadataMenu);
            app.LoadstageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadstageMenuSelected, true);
            app.LoadstageMenu.Text = 'Load stage';
            app.LoadarousaldataMenu = uimenu(app.LoadanadataMenu);
            app.LoadarousaldataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadarousaldataMenuSelected, true);
            app.LoadarousaldataMenu.Text = 'Load arousal data';
            app.LoadexpertstageMenu = uimenu(app.LoadanadataMenu);
            app.LoadexpertstageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadexpertstageMenuSelected, true);
            app.LoadexpertstageMenu.Text = 'Load expert stage';
            app.AnalysisMenu = uimenu(app.UIFigure);
            app.AnalysisMenu.Text = 'Analysis';
            app.AutoScoringMenu = uimenu(app.AnalysisMenu);
            app.AutoScoringMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoScoringMenuSelected, true);
            app.AutoScoringMenu.Text = 'Auto Scoring';
            app.AutoScoringwithlowreliabilityMenu = uimenu(app.AnalysisMenu);
            app.AutoScoringwithlowreliabilityMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoScoringwithlowreliabilityMenuSelected, true);
            app.AutoScoringwithlowreliabilityMenu.Text = 'Auto Scoring with low reliability';
            app.expertdisscusionMenu = uimenu(app.UIFigure);
            app.expertdisscusionMenu.MenuSelectedFcn = createCallbackFcn(app, @expertdisscusionMenuSelected, true);
            app.expertdisscusionMenu.Text = 'expert disscusion';
            app.Panel = uipanel(app.UIFigure);
            app.stage_pic = uiaxes(app.Panel);
            app.stage_pic.XTick = [];
            app.stage_pic.YTick = [];
            app.save = uibutton(app.Panel, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.save.Text = 'Save Dat';
            app.Slider = uislider(app.Panel);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.currentdataEditFieldLabel = uilabel(app.Panel);
            app.currentdataEditFieldLabel.Text = 'current data';
            app.currentdata = uieditfield(app.Panel, 'text');
            app.savedddressEditFieldLabel = uilabel(app.Panel);
            app.savedddressEditFieldLabel.Text = 'save dddress';
            app.savedddress = uieditfield(app.Panel, 'text');
            app.savedddress.ValueChangedFcn = createCallbackFcn(app, @savedddressValueChanged, true);
            app.Brwose = uibutton(app.Panel, 'push');
            app.Brwose.ButtonPushedFcn = createCallbackFcn(app, @BrwoseButtonPushed, true);
            app.Brwose.Text = 'Browse';
            app.SamplerateEditFieldLabel = uilabel(app.Panel);
            app.SamplerateEditFieldLabel.Text = 'Sample rate';
            app.fsEdit = uieditfield(app.Panel, 'numeric');
            app.fsEdit.ValueChangedFcn = createCallbackFcn(app, @fsEditValueChanged, true);
            app.fsEdit.Value = 250;
            app.PageLabel = uilabel(app.Panel);
            app.PageLabel.Text = 'Page';
            app.PageEdit = uieditfield(app.Panel, 'numeric');
            app.PageEdit.ValueChangedFcn = createCallbackFcn(app, @PageEditValueChanged, true);
            app.save_Arousal = uibutton(app.Panel, 'push');
            app.save_Arousal.ButtonPushedFcn = createCallbackFcn(app, @save_ArousalButtonPushed, true);
            app.save_Arousal.Text = 'Save Arousal';
            app.ZoomEditFieldLabel = uilabel(app.Panel);
            app.ZoomEditFieldLabel.Text = 'Zoom';
            app.ZoomEditField = uieditfield(app.Panel, 'numeric');
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.ZoomEditField.Value = 1;
            app.ChannelLabel = uilabel(app.Panel);
            app.ChannelLabel.Text = 'Channel';
            app.channelDropDown = uidropdown(app.Panel);
            app.channelDropDown.Items = {};
            app.channelDropDown.ValueChangedFcn = createCallbackFcn(app, @channelDropDownValueChanged, true);
            app.channelDropDown.Value = {};
            app.TabGroup = uitabgroup(app.Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.PSGTab = uitab(app.TabGroup);
            app.PSGTab.Title = 'PSG';
            app.PSG_axes = uiaxes(app.PSGTab);
            app.PSG_axes.XLim = [0 30];
            app.PSG_axes.YLim = [0 150];
            app.PSG_axes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.PSG_axes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'; '26'; '28'; '30'};
            app.PSG_axes.YTick = [];
            app.PSG_axes.YTickLabel = '';
            app.View_eventTab = uitab(app.TabGroup);
            app.View_eventTab.Title = 'View_event';
            app.event_axes = uiaxes(app.View_eventTab);
            app.event_axes.XLim = [0 30];
            app.event_axes.YLim = [0 150];
            app.event_axes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.event_axes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'; '26'; '28'; '30'};
            app.event_axes.YTick = [];
            app.event_axes.YTickLabel = '';
            app.AgreementAnalysisButton = uibutton(app.Panel, 'push');
            app.AgreementAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AgreementAnalysisButtonPushed, true);
            app.AgreementAnalysisButton.Text = 'Agreement Analysis';
            app.Label = uilabel(app.Panel);
            app.AgreementLabel = uilabel(app.Panel);
            app.AgreementLabel.Text = 'Agreement:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PSG_chun

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end