classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SleepIndexLabel     matlab.ui.control.Label
        sleepIndex          matlab.ui.control.Table
        Agreement           matlab.ui.control.Label
        TheAgreementLabel   matlab.ui.control.Label
        YourscoringLabel    matlab.ui.control.Label
        ExpertscoringLabel  matlab.ui.control.Label
        ConfuseMatrix       matlab.ui.control.Table
        stage_pic           matlab.ui.control.UIAxes
        expertstage_pic     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp   % Main app object 
        stage % Descriptioncla(app.stage_pic);
        expertstage % Description
        pagemax % Description
        T % Description
        T2 % Description
    end
    
    methods (Access = private)
        function draw_stage(app)
            cla(app.expertstage_pic);
            set(app.expertstage_pic,'xLim',[1 app.pagemax])
            set(app.expertstage_pic,'xTick',(1:100:app.pagemax));
            set(app.expertstage_pic,'yLim',[-4 2])
            set(app.expertstage_pic,'yTick',(-4:1:2));
            set(app.expertstage_pic,'yTickLabel',["","N3","N2","N1","W","R",""]);
            hold(app.expertstage_pic,"on");
            %app.stage=app.stage*-1;
            %plot(app.stage_pic,[app.page app.page],[-4 2],'r');
            %app.expertstage=app.expertstage*-1;
            %plot(app.expertstage_pic,[app.page app.page],[-4 2],'r');
            if ~isempty(app.expertstage)
                W=app.expertstage==0;
                R=app.expertstage==-1;
                bar(app.expertstage_pic,R,'FaceColor','#A2142F','BarWidth',1)
                N1=app.expertstage==1;
                bar(app.expertstage_pic,N1*-1,'FaceColor','#EDB120','BarWidth',1)
                N2=app.expertstage==2;
                bar(app.expertstage_pic,N2*-2,'FaceColor','#77AC30','BarWidth',1)
                N3=app.expertstage==3;
                bar(app.expertstage_pic,N3*-3,'FaceColor','#0072BD','BarWidth',1)
                %stairs(app.stage_pic,app.expertstage*-1,'r');
            end
            cla(app.stage_pic);
            set(app.stage_pic,'xLim',[1 app.pagemax])
            set(app.stage_pic,'xTick',(1:100:app.pagemax));
            set(app.stage_pic,'yLim',[-4 2])
            set(app.stage_pic,'yTick',(-4:1:2));
            set(app.stage_pic,'yTickLabel',["","N3","N2","N1","W","R",""]);
            hold(app.stage_pic,"on");
            if ~isempty(app.stage)
                W=app.stage==0;
                R=app.stage==-1;
                bar(app.stage_pic,R,'FaceColor','#A2142F','BarWidth',1)
                N1=app.stage==1;
                bar(app.stage_pic,N1*-1,'FaceColor','#EDB120','BarWidth',1)
                N2=app.stage==2;
                bar(app.stage_pic,N2*-2,'FaceColor','#77AC30','BarWidth',1)
                N3=app.stage==3;
                bar(app.stage_pic,N3*-3,'FaceColor','#0072BD','BarWidth',1)
                %stairs(app.stage_pic,app.stage*-1,'g');
            end
        end
        
        function CM(app)
            if ~isempty(app.stage) && ~isempty(app.expertstage)
                stage2num=containers.Map([0,1,2,3,-1],[1 2 3 4 5]);
                gold_cm=zeros(5,5);
                for j=1:length(app.stage)
                    gold_cm(stage2num(app.expertstage(j)),stage2num(app.stage(j)))=gold_cm(stage2num(app.expertstage(j)),stage2num(app.stage(j)))+1;
                end
                for i=1:5
                    gold_cm_pre(i,1:5) = string(round(gold_cm(i,:)./sum(gold_cm(i,:))*100))+'%';
                end
                
                idx=["Wake";"N1";"N2";"N3";"REM"];
                Wake=gold_cm_pre(:,1);N1=gold_cm_pre(:,2);N2=gold_cm_pre(:,3);N3=gold_cm_pre(:,4);REM=gold_cm_pre(:,5);
                app.T=table(Wake,N1,N2,N3,REM,'RowNames',idx);
                app.ConfuseMatrix.Data=app.T{:,:};
                app.ConfuseMatrix.ColumnName = app.T.Properties.VariableNames;
                app.ConfuseMatrix.RowName = app.T.Properties.RowNames;
                app.Agreement.Text=round(length(find(app.stage==app.expertstage))/length(app.stage),3)*100+"%";

                W=find(app.stage==0);
                NW=find(app.stage~=0);
                N1=string(round(length(find(app.stage==1))/length(find(app.stage~=0)),3)*100)+"%";
                N2=string(round(length(find(app.stage==2))/length(find(app.stage~=0)),3)*100)+"%";
                N3=string(round(length(find(app.stage==3))/length(find(app.stage~=0)),3)*100)+"%";
                R=string(round(length(find(app.stage==-1))/length(find(app.stage~=0)),3)*100)+"%";
                Awake=string(round(length(find(app.stage==0))*30/60,1));
                W=app.stage==0;
                tmp=find(W==0);%NW
                TST=string(length(tmp)/2);
                SOT=string(tmp(1)/2);
                SE=string(round(length(tmp)/app.pagemax,3)*100)+"%";
                tmp=find(W(tmp(1):end)==1);
                WASOT=string(length(tmp)/2);
                SleepIndex=[TST;SOT;SE;WASOT;N1;N2;N3;R;Awake];
                clear W NW N1 N2 N3 R Awake tmp TST SOT SE WASOT
                %W=find(app.expertstage==0);
                %NW=find(app.expertstage~=0);
                W=app.expertstage==0;
                N1=string(round(length(find(app.expertstage==1))/length(find(app.expertstage~=0)),3)*100)+"%";
                N2=string(round(length(find(app.expertstage==2))/length(find(app.expertstage~=0)),3)*100)+"%";
                N3=string(round(length(find(app.expertstage==3))/length(find(app.expertstage~=0)),3)*100)+"%";
                R=string(round(length(find(app.expertstage==-1))/length(find(app.expertstage~=0)),3)*100)+"%";
                Awake=string(round(length(find(app.expertstage==0))*30/60,1));
                tmp=find(W==0);%NW
                TST=string(length(tmp)/2);
                SOT=string(tmp(1)/2);
                SE=string(round(length(tmp)/app.pagemax,3)*100)+"%";
                tmp=find(W(tmp(1):end)==1);
                WASOT=string(length(tmp)/2);
                ExpertSleepIndex=[TST;SOT;SE;WASOT;N1;N2;N3;R;Awake];
                SIidx=["TST";"SOT";"SE";"WASOT";"N1(%)";"N2(%)";"N3(%)";"REM(%)";"Awake(m)"];
                app.T2=table(ExpertSleepIndex,SleepIndex,'RowNames',SIidx);
                app.sleepIndex.Data=app.T2{:,:};
                app.sleepIndex.ColumnName = app.T2.Properties.VariableNames;
                app.sleepIndex.RowName = app.T2.Properties.RowNames;
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, expertstage, stage, pagemax)
             % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.expertstage=expertstage;
            app.stage=stage;
            app.pagemax=pagemax;
            draw_stage(app);
            CM(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.expertstage_pic = uiaxes(app.UIFigure);
            title(app.expertstage_pic, 'expert scoring')
            app.expertstage_pic.XTick = [];
            app.expertstage_pic.XTickLabel = '';
            app.expertstage_pic.YTick = [];
            app.expertstage_pic.YTickLabel = '';
            app.stage_pic = uiaxes(app.UIFigure);
            title(app.stage_pic, 'your scoring')
            app.stage_pic.XTick = [];
            app.stage_pic.YTick = [];
            app.ConfuseMatrix = uitable(app.UIFigure);
            app.ConfuseMatrix.ColumnName = {''; 'Wake'; 'N1'; 'N2'; 'N3'; 'REM'};
            app.ConfuseMatrix.RowName = {};
            app.ExpertscoringLabel = uilabel(app.UIFigure);
            app.ExpertscoringLabel.Text = {'Expert '; 'scoring'};
            app.YourscoringLabel = uilabel(app.UIFigure);
            app.YourscoringLabel.Text = 'Your scoring';
            app.TheAgreementLabel = uilabel(app.UIFigure);
            app.TheAgreementLabel.Text = 'The Agreement:';
            app.Agreement = uilabel(app.UIFigure);
            app.Agreement.Text = '';
            app.sleepIndex = uitable(app.UIFigure);
            app.sleepIndex.ColumnName = '';
            app.sleepIndex.RowName = {};
            app.SleepIndexLabel = uilabel(app.UIFigure);
            app.SleepIndexLabel.Text = 'Sleep Index';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end